classdef GRAFICAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        advertencia_incidencia        matlab.ui.control.Label
        Num_arista                    matlab.ui.control.Label
        Ingrese_arista                matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        MatrizIncidenciaButton        matlab.ui.control.Button
        MatrizAdyacenciaButton        matlab.ui.control.Button
        RefresaraMenuPrincipalButton  matlab.ui.control.Button
        IngresarAristaButton          matlab.ui.control.Button
        ConLabel                      matlab.ui.control.Label
        Vertice2                      matlab.ui.control.Spinner
        VerticeSpinner_2Label         matlab.ui.control.Label
        Vertice1                      matlab.ui.control.Spinner
        VerticeSpinnerLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        G; % Description
        h;%se almacena el plot
        i=1;%contador
        %s=[];%vector s
        %t=[];%vector t  
        ID_aristas=[];%%este vector almacena el ID de cada arista
        cantidad_vertices;%esta variable almacena la cantidad de aristas de la grafica
        numero_arista=2;%indica que arista se va a ingresar 

    end
    
    methods (Access = private)
        
        function  etiquetas(app)
            app.ID_aristas(app.i)=app.i;
            app.i=app.i+1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vertices)
            app.cantidad_vertices=vertices;
            limites=[];
            limites(1)=1;
            limites(2)=vertices;
            app.G=graph;
            app.G=app.G.addnode(vertices);      % metodo constructr
            app.Vertice1.Limits=limites;
            app.Vertice2.Limits=limites;
            app.h=plot(app.G);
        end

        % Button pushed function: IngresarAristaButton
        function IngresarAristaButtonPushed(app, event)
            app.advertencia_incidencia.Text='';
            app.G=app.G.addedge(app.Vertice1.Value,app.Vertice2.Value);
            etiquetas(app);
            app.h=plot(app.G);
            labeledge(app.h,app.ID_aristas,app.ID_aristas);
            app.Num_arista.Text=char(""+app.numero_arista);
            app.numero_arista=app.numero_arista+1;
        end

        % Button pushed function: MatrizAdyacenciaButton
        function MatrizAdyacenciaButtonPushed(app, event)
            num_vertices=app.cantidad_vertices;% esta variable almacena la cantidad de vertices de la grafica
            variable_names={};
            matriz={};
            valor=0;
            

            for filas=0:num_vertices%%este for reccorre las filas
                for columnas=1:(num_vertices+1)% este for recorre las columnas


                    if filas==0%%este if controla el encabezado
                        if columnas == 1
                            variable_names(1)={"Vertice"};
                        else   
                            variable_names(columnas)={""+(columnas-1)};
                        end
                    
                    else%%else condicional
                        
                        
                        if columnas==1
                            matriz(filas,columnas)={filas};
                        else
                            
                            valor=findedge(app.G,filas,columnas-1);%DEVUELVE 0 SI EXISTE LA ARISTA
                            if valor==0
                                matriz(filas,columnas)={0};
                            else
                                matriz(filas,columnas)={1};
                            end

                        end

                    end%%final condicional


                end%%llave final for interno
            end%%llafi final for externo
            ruta_archivo = 'matriz_adyacencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);
            str=string(variable_names);
            T=cell2table(matriz,"VariableNames",str);
            writetable(T, 'matriz_adyacencia.xlsx')
            system('matriz_adyacencia.xlsx');


        end

        % Button pushed function: MatrizIncidenciaButton
        function MatrizIncidenciaButtonPushed(app, event)
                vertices=app.G.numnodes;%se obtienen el numero de vertices
                aristas=app.G.numedges;% se obtiene el numero de aristas
                variable_names={};% en esta se almacenaran los nombres de las columnas
                matriz={};%se almacenara la matria
                a=0;
                b=0;
                if aristas==0
                    app.advertencia_incidencia.Text='Ingresa al menos una arista para continuar!!';
                    return;
                end
                
                for index=1:(aristas+1)%eate for llena la parte superior de la hoja de excel
                    if index==1
                        variable_names(1)={"Aristas/Vertices"};
                    else
                        variable_names(index)={"e"+(index-1)};
                    end    
                end%llave final del for


                for index=1:vertices% este for identidica el vertice
                    matriz(index,1)={index};
                end%final del for
                
                %este for llenara la matriz de adyacencia

                for columna=1:aristas%for externo
                    [a,b]=app.G.findedge(columna);
                    for fila=1:vertices
                        if a==fila || b==fila
                            matriz(fila,columna+1)={1};
                        else
                            matriz(fila,columna+1)={0};
                        end
                    end
                end%final for externo
            ruta_archivo = 'matriz_incidencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);
            str=string(variable_names);
            T=cell2table(matriz,"VariableNames",str);
            writetable(T,'matriz_incidencia.xlsx');
            system('matriz_incidencia.xlsx');
            

        end

        % Button pushed function: RefresaraMenuPrincipalButton
        function RefresaraMenuPrincipalButtonPushed(app, event)
            app2();
            
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VerticeSpinnerLabel = uilabel(app.UIFigure);
            app.VerticeSpinnerLabel.Text = 'Vertice';
            app.Vertice1 = uispinner(app.UIFigure);
            app.VerticeSpinner_2Label = uilabel(app.UIFigure);
            app.VerticeSpinner_2Label.Text = 'Vertice';
            app.Vertice2 = uispinner(app.UIFigure);
            app.ConLabel = uilabel(app.UIFigure);
            app.ConLabel.Text = 'Con';
            app.IngresarAristaButton = uibutton(app.UIFigure, 'push');
            app.IngresarAristaButton.ButtonPushedFcn = createCallbackFcn(app, @IngresarAristaButtonPushed, true);
            app.IngresarAristaButton.Text = 'Ingresar Arista';
            app.RefresaraMenuPrincipalButton = uibutton(app.UIFigure, 'push');
            app.RefresaraMenuPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @RefresaraMenuPrincipalButtonPushed, true);
            app.RefresaraMenuPrincipalButton.Text = 'Refresar a Menu Principal';
            app.MatrizAdyacenciaButton = uibutton(app.UIFigure, 'push');
            app.MatrizAdyacenciaButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizAdyacenciaButtonPushed, true);
            app.MatrizAdyacenciaButton.Text = 'Matriz Adyacencia';
            app.MatrizIncidenciaButton = uibutton(app.UIFigure, 'push');
            app.MatrizIncidenciaButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizIncidenciaButtonPushed, true);
            app.MatrizIncidenciaButton.Text = 'Matriz Incidencia';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Ingrese_arista = uilabel(app.UIFigure);
            app.Ingrese_arista.Text = 'Ingrese la Arista: ';
            app.Num_arista = uilabel(app.UIFigure);
            app.Num_arista.Text = '1';
            app.advertencia_incidencia = uilabel(app.UIFigure);
            app.advertencia_incidencia.Text = 'Debes ingresar al menos una arista para esta opcion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GRAFICAS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end